classdef AktorVisualisierer2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AllgemeinesPanel             matlab.ui.container.Panel
        KurvezeichnenCheckBox        matlab.ui.control.CheckBox
        BahnverlaufzeichnenCheckBox  matlab.ui.control.CheckBox
        BildmaeinPixelLabel          matlab.ui.control.Label
        BildmaeinPixelEditField      matlab.ui.control.NumericEditField
        xEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        Datenpunkttepro1000Label     matlab.ui.control.Label
        Datenpunkttepro1000Spinner   matlab.ui.control.Spinner
        DatensatzwhlenButton         matlab.ui.control.Button
        KeinDatensatzgewhltLabel     matlab.ui.control.Label
        AchsengrenzenPanel           matlab.ui.container.Panel
        AutomatischsetzenCheckBox    matlab.ui.control.CheckBox
        UntereGrenzeLabel            matlab.ui.control.Label
        ObereGrenzeLabel             matlab.ui.control.Label
        xAchseLabel                  matlab.ui.control.Label
        yAchseLabel                  matlab.ui.control.Label
        zAchseLabel                  matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_6                  matlab.ui.control.NumericEditField
        KamerapositionPanel          matlab.ui.container.Panel
        AzimuthLabel                 matlab.ui.control.Label
        AzimuthEditField             matlab.ui.control.NumericEditField
        ElevationLabel               matlab.ui.control.Label
        ElevationEditField           matlab.ui.control.NumericEditField
        RenderButton                 matlab.ui.control.Button
        AnimationPanel               matlab.ui.container.Panel
        AnimationerstellenCheckBox   matlab.ui.control.CheckBox
        EinzelneSegmenteCheckBox     matlab.ui.control.CheckBox
        EinzelneKurvemitBahnverlaufCheckBox  matlab.ui.control.CheckBox
        BilderproSekundeLabel        matlab.ui.control.Label
        BilderproSekundeEditField    matlab.ui.control.NumericEditField
        Zeitfaktor20Label            matlab.ui.control.Label
        BedingtdurchDatenpunktepro1000undBildersLabel  matlab.ui.control.Label
        DateinameLabel               matlab.ui.control.Label
        DateinameEditField           matlab.ui.control.EditField
        SpeicherortwhlenButton       matlab.ui.control.Button
        TextArea_2                   matlab.ui.control.TextArea
        TextArea                     matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        FileName = -1;
        FilePath = -1;
        savePath = pwd;
    end
    
    methods (Access = private)
        
        %Function to compute an amount of points on the circle segment described by
        %the values obtained by the simulation.
        function [p3,h] = line2(app, k, sinPhi, cosPhi, s, d, plotCurve)
            r = 1/k;
            pM = [r * cosPhi; r * sinPhi; d];
            p1 = [0;0;d];
            a = s/r;
            cosa = cos(a);
            sina = sin(a);
            % p2 per rotation matrix
            % x = p1-pM;
            % n = cross(pM, [0;0;1]);
            % n = -n/norm(n);
            % n1 = n(1); n2 = n(2); n3 = n(3);
            %R = [n1*n1*(1-cosa)+cosa n1*n2*(1-cosa)-n3*sina n1*n3*(1-cosa)+n2*sina;
            %     n2*n1*(1-cosa)+n3*sina n2*n2*(1-cosa)+cosa n2*n3*(1-cosa)-n1*sina;
            %      n3*n1*(1-cosa)-n2*sina n3*n2*(1-cosa)+n1*sina n3*n3*(1-cosa)+cosa];
            
            %p2 = R*x+pM;
            %or shorter
            % p2 = n*dot(n,x)+cosa*cross(cross(n,x),n)+sina*cross(n,x)+pM;
            
            p2 = p1 + (pM-p1)*(1-cosa) + [0;0;r*sina];
            v1 = p1 - pM;
            v2 = p2 - pM;
            p3 = -cross(v2, cross(v1,v2));
            p3 = p3/norm(p3)*d + p2;
            h = [];
            if plotCurve
                segments = 100;
                v3 = cross(cross(v1,v2),v1);
                v3 = r*v3/norm(v3);
                t = linspace(0,atan2(norm(cross(v1,v2)),dot(v1,v2)),segments);
                v = v1*cos(t)+v3*sin(t)+pM;
                h = horzcat([0; 0; 0], v, p3);
            end
            
        end
    end
        

    methods (Access = private)

        % Value changed function: AnimationerstellenCheckBox
        function AnimationerstellenCheckBoxValueChanged(app, event)
                if app.AnimationerstellenCheckBox.Value == 0
                    app.EinzelneSegmenteCheckBox.Value = 0;
                    app.EinzelneKurvemitBahnverlaufCheckBox.Value = 0;
                    state = 'off';
                else
                    state = 'on';
                end
                app.EinzelneKurvemitBahnverlaufCheckBox.Enable = state;
                app.EinzelneSegmenteCheckBox.Enable = state;
                app.BilderproSekundeEditField.Enable = state;
                app.BilderproSekundeLabel.Enable = state;
                app.Zeitfaktor20Label.Enable = state;
                app.BedingtdurchDatenpunktepro1000undBildersLabel.Enable = state;
                app.DateinameLabel.Enable = state;
                app.DateinameEditField.Enable = state;
                app.SpeicherortwhlenButton.Enable = state;
                app.TextArea_2.Enable = state;
        end

        % Value changed function: AutomatischsetzenCheckBox
        function AutomatischsetzenCheckBoxValueChanged(app, event)
                if app.AutomatischsetzenCheckBox.Value == 0
                    state = 'on';
                else
                    state = 'off';
                end
                app.EditField.Enable = state;
                app.EditField_3.Enable = state;
                app.EditField_5.Enable = state;
                app.EditField_2.Enable = state;
                app.EditField_4.Enable = state;
                app.EditField_6.Enable = state;
                app.xAchseLabel.Enable = state;
                app.yAchseLabel.Enable = state;
                app.zAchseLabel.Enable = state;
                app.ObereGrenzeLabel.Enable = state;
                app.UntereGrenzeLabel.Enable = state;
                
        end

        % Value changed function: BahnverlaufzeichnenCheckBox
        function BahnverlaufzeichnenCheckBoxValueChanged(app, event)
                if app.BahnverlaufzeichnenCheckBox.Value == 0
                    app.EinzelneKurvemitBahnverlaufCheckBox.Value = 0;
                end
        end

        % Value changed function: BilderproSekundeEditField
        function BilderproSekundeEditFieldValueChanged(app, event)
                nVal = app.BilderproSekundeEditField.Value;
                if mod(nVal, 1) == 0
                    app.Zeitfaktor20Label.Text = ['Zeitfaktor: ' num2str(round(nVal/app.Datenpunkttepro1000Spinner.Value,2))];
                else
                    msgbox(['Wert muss ein Integer sein.'],'Error','error');
                    app.BilderproSekundeEditField.Value = event.PreviousValue;
                end
        end

        % Value changed function: BildmaeinPixelEditField
        function BildmaeinPixelEditFieldValueChanged(app, event)
                nVal = app.BildmaeinPixelEditField.Value;
                maxVal = get(0,'ScreenSize');
                maxVal = maxVal(3);
                if ~(mod(nVal, 1) == 0 && nVal <= maxVal)
                    msgbox(['Wert muss ein Integer kleiner gleich der momentanen Bildschirmbreite in Pixel sein.'],'Error','error');
                    app.BildmaeinPixelEditField.Value = event.PreviousValue;
                end
        end

        % Value changed function: Datenpunkttepro1000Spinner
        function Datenpunkttepro1000SpinnerValueChanged(app, event)
                nVal = app.Datenpunkttepro1000Spinner.Value;
                if mod(nVal, 1) == 0
                    app.Zeitfaktor20Label.Text = ['Zeitfaktor: ' num2str(round(app.BilderproSekundeEditField.Value/nVal,2))];
                else
                    msgbox(['Wert muss ein Integer sein.'],'Error','error');
                    app.Datenpunkttepro1000Spinner.Value = event.PreviousValue;
                end
        end

        % Button pushed function: DatensatzwhlenButton
        function DatensatzwhlenButtonPushed(app, event)
                [filename, filepath] = uigetfile('*.mat');
                if filename ~= 0
                    app.FileName = filename;
                    app.FilePath = filepath;
                    app.KeinDatensatzgewhltLabel.Text = filename;
                end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
                nVal = app.EditField.Value;
                if nVal >= app.EditField_2.Value
                    msgbox('Wert muss kleiner als der Wert der oberen Grenze sein.','Error','error');
                    app.EditField.Value = event.PreviousValue;
                end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
                nVal = app.EditField_2.Value;
                if nVal <= app.EditField.Value
                    msgbox(['Wert muss gr' char(246) char(223) 'als der Wert der oberen Grenze sein.'],'Error','error');
                    app.EditField_2.Value = event.PreviousValue;
                end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
                nVal = app.EditField_3.Value;
                if nVal >= app.EditField_4.Value
                    msgbox('Wert muss kleiner als der Wert der oberen Grenze sein.','Error','error');
                    app.EditField_3.Value = event.PreviousValue;
                end
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
                nVal = app.EditField_4.Value;
                if nVal <= app.EditField_3.Value
                    msgbox(['Wert muss gr' char(246) char(223) 'als der Wert der oberen Grenze sein.'],'Error','error');
                    app.EditField_4.Value = event.PreviousValue;
                end
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
                nVal = app.EditField_5.Value;
                if nVal >= app.EditField_6.Value
                    msgbox('Wert muss kleiner als der Wert der oberen Grenze sein.','Error','error');
                    app.EditField_5.Value = event.PreviousValue;
                end
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
                nVal = app.EditField_6.Value;
                if nVal <= app.EditField_5.Value
                    msgbox(['Wert muss gr' char(246) char(223) 'als der Wert der oberen Grenze sein.'],'Error','error');
                    app.EditField_6.Value = event.PreviousValue;
                end
        end

        % Value changed function: EinzelneKurvemitBahnverlaufCheckBox
        function EinzelneKurvemitBahnverlaufCheckBoxValueChanged(app, event)
                app.KurvezeichnenCheckBox.Value = 1;
                app.BahnverlaufzeichnenCheckBox.Value = 1;
                app.AnimationerstellenCheckBox.Value = 1;
                app.EinzelneSegmenteCheckBox.Value = 1;
        end

        % Value changed function: EinzelneSegmenteCheckBox
        function EinzelneSegmenteCheckBoxValueChanged(app, event)
                if app.EinzelneSegmenteCheckBox.Value == 0
                    app.EinzelneKurvemitBahnverlaufCheckBox.Value = 0;
                end
        end

        % Value changed function: KurvezeichnenCheckBox
        function KurvezeichnenCheckBoxValueChanged(app, event)
                if app.KurvezeichnenCheckBox.Value == 0
                    app.EinzelneKurvemitBahnverlaufCheckBox.Value = 0;
                end
        end

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)
                startTime = tic;
                
                if isequal(app.FilePath, -1)
                    msgbox(['Keine Datei gew' char(228) 'hlt.'],'Error','error')
                    return
                end
                singleLineTrajectory = app.EinzelneKurvemitBahnverlaufCheckBox.Value;
                drawBodyCurve = app.KurvezeichnenCheckBox.Value;
                drawTrajectory = app.BahnverlaufzeichnenCheckBox.Value;
                if ~drawBodyCurve && ~drawTrajectory
                    msgbox('''Kurve zeichnen'' oder ''Bahnverlauf zeichnen'' muss gesetzt sein.','Error','error')
                    return
                end
                doExport =  app.AnimationerstellenCheckBox.Value;
                clearFigure = app.EinzelneSegmenteCheckBox.Value;
                dataResolution = app.Datenpunkttepro1000Spinner.Value;
                
                config = load(strcat(app.FilePath, app.FileName));
                config = config.config;
                
                maxI = size(config, 1);
                framenumber = round(maxI/1000*min([1000 dataResolution]));
                indeces = round(linspace(1, maxI, framenumber));
                if  drawTrajectory
                    headData = zeros(3,framenumber);
                end
                if drawBodyCurve
                    bodyData = cell(1,framenumber);
                end
                i2 = 1;
                for i = indeces
                    x = config(i,:);
                    if drawTrajectory && ~drawBodyCurve
                        headData(:,i2) = line2(app, x(1), x(2), x(3), x(4), x(5),0);
                    elseif ~drawTrajectory && drawBodyCurve
                        [~,bodyData{:,i2}] = line2(app, x(1), x(2), x(3), x(4), x(5),1);
                    elseif drawBodyCurve && drawTrajectory
                        [headData(:,i2),bodyData{:,i2}] = line2(app, x(1), x(2), x(3), x(4), x(5),1);
                    end
                    i2 = i2 + 1;
                end
                f1 = figure('Position', [0 0 app.BildmaeinPixelEditField.Value app.xEditField.Value], 'PaperPositionMode','auto');
                colormap(f1,[0.3 0.3 0.3])
                lightangle(40,15)
                xlabel('x');ylabel('y');zlabel('z');
                daspect([1 1 1]);
                view(app.AzimuthEditField.Value,app.ElevationEditField.Value);
                if app.AutomatischsetzenCheckBox.Value == 0
                    xlim([app.EditField.Value app.EditField_2.Value]);
                    ylim([app.EditField_3.Value app.EditField_4.Value]);
                    zlim([app.EditField_5.Value app.EditField_6.Value]);
                else
                    if drawBodyCurve
                        t = cell2mat(bodyData);
                        xlim([min(t(1, 1:end))-5 max(t(1, 1:end))+5]);
                        ylim([min(t(2, 1:end))-5 max(t(2, 1:end))+5]);
                        zlim([min(t(3, 1:end))-5 max(t(3, 1:end))+5]);
                    else
                        xlim([min(headData(1, 1:end)) max(headData(1, 1:end))]);
                        ylim([min(headData(2, 1:end)) max(headData(2, 1:end))]);
                        zlim([min(headData(3, 1:end)) max(headData(3, 1:end))]);
                    end
                end
                hold on
                ax = gca;
                if doExport
                    fileLocation = [app.savePath filesep app.DateinameEditField.Value];
                    if ispc || ismac
                        v = VideoWriter(fileLocation, 'MPEG-4');
                    else
                        v = VideoWriter(fileLocation, 'Motion JPEG AVI');
                    end
                    v.FrameRate = app.BilderproSekundeEditField.Value;
                    v.Quality = 100;
                    open(v);
                    meanLoopTime = 0;
                    for i = 1:framenumber
                        loopStartTime = tic;
                        if clearFigure
                            if drawTrajectory
                                if singleLineTrajectory
                                    plot3(ax,headData(1,1:i),headData(2,1:i),headData(3,1:i),'-r');
                                else
                                    i2 = max(1, i-1);
                                    plot3(ax,headData(1,i2:i),headData(2,i2:i),headData(3,i2:i),'-r');
                                end
                            end
                            if drawBodyCurve
                                x = bodyData{:,i};
                                h = streamtube(ax,{[x(1,:);x(2,:);x(3,:)]'}, 3);
                                h.FaceLighting = 'gouraud';
                                h.AmbientStrength = 0.8;
                                h.DiffuseStrength = 0.7;
                                h.SpecularStrength = 0.8;
                                h.SpecularExponent = 15;
                                h.BackFaceLighting = 'unlit';
                                lightangle(40,15)
                                shading interp
                            end
                            writeVideo(v, getframe(f1));
                            cla(f1);
                        else
                            if drawTrajectory
                                j = plot3(ax,headData(1,1:i),headData(2,1:i),headData(3,1:i),'-r');
                            end
                            if drawBodyCurve
                                x = bodyData{:,i};
                                h = streamtube(ax,{[x(1,:);x(2,:);x(3,:)]'}, 3);
                                h.FaceLighting = 'gouraud';
                                h.AmbientStrength = 0.8;
                                h.DiffuseStrength = 0.7;
                                h.SpecularStrength = 0.8;
                                h.SpecularExponent = 15;
                                h.BackFaceLighting = 'unlit';
                                shading interp
                            end
                            writeVideo(v, getframe(f1));
                            if drawTrajectory
                                delete(j)
                            end
                        end
                        loopEndTime = toc(loopStartTime);
                        meanLoopTime = (meanLoopTime * (i-1) + loopEndTime)/(i);
                        rTime = round(meanLoopTime*(framenumber-i),2);
                        app.TextArea.Value = sprintf('%d of %d Frames (%.2f%%)\nEstimated time remaining: %.0fh %.0fm %.0fs',...
                            i, framenumber, round(i/framenumber*100,2), floor(rTime/3600),floor(mod(rTime/60,60)), floor(mod(rTime,60)));
                    end
                else
                    if drawTrajectory
                        plot3(ax, headData(1,:),headData(2,:),headData(3,:),'-r');
                    end
                    if drawBodyCurve
                        for i = 1:framenumber
                            x = bodyData{:,i};
                            h = streamtube(ax, {[x(1,:);x(2,:);x(3,:)]'}, 3);
                            h.FaceLighting = 'gouraud';
                            h.AmbientStrength = 0.8;
                            h.DiffuseStrength = 0.7;
                            h.SpecularStrength = 0.8;
                            h.SpecularExponent = 15;
                            h.BackFaceLighting = 'unlit';
                        end
                        shading interp
                    end
                end
                % savefig(f1, 'fig_script_compact.fig', 'compact');
                rTime = toc(startTime);
                app.TextArea.Value = sprintf('Time elapsed: %.0fh %.0fm %.0fs %.0fms', ...
                    floor(rTime/3600),floor(mod(rTime/60,60)), floor(mod(rTime,60)), (rTime-floor(rTime))*1000);
        end

        % Button pushed function: SpeicherortwhlenButton
        function SpeicherortwhlenButtonPushed(app, event)
                filepath = uigetdir;
                drawnow;
                if filepath ~= 0
                    %checking permisions does not properly work currently
                    [~,f] = fileattrib(filepath);
                    if f.UserWrite == 1
                        app.savePath = filepath;
                        app.TextArea_2.Value = filepath;
                    else
                        msgbox('Keine Schreibberechtigung.','Error','error');
                    end
                end
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
                nVal = app.xEditField.Value;
                maxVal = get(0,'ScreenSize');
                maxVal = maxVal(4);
                if ~(mod(nVal, 1) == 0 && nVal <= maxVal)
                    msgbox(['Wert muss ein Integer kleiner gleich der momentanen Bildschirmhöhe in Pixel sein.'],'Error','error');
                    app.xEditField.Value = event.PreviousValue;
                end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AllgemeinesPanel = uipanel(app.UIFigure);
            app.AllgemeinesPanel.Title = 'Allgemeines';
            app.KurvezeichnenCheckBox = uicheckbox(app.AllgemeinesPanel);
            app.KurvezeichnenCheckBox.ValueChangedFcn = createCallbackFcn(app, @KurvezeichnenCheckBoxValueChanged, true);
            app.KurvezeichnenCheckBox.Text = 'Kurve zeichnen';
            app.BahnverlaufzeichnenCheckBox = uicheckbox(app.AllgemeinesPanel);
            app.BahnverlaufzeichnenCheckBox.ValueChangedFcn = createCallbackFcn(app, @BahnverlaufzeichnenCheckBoxValueChanged, true);
            app.BahnverlaufzeichnenCheckBox.Text = 'Bahnverlauf zeichnen';
            app.BildmaeinPixelLabel = uilabel(app.AllgemeinesPanel);
            app.BildmaeinPixelLabel.Text = 'Bildmaße in Pixel:';
            app.BildmaeinPixelEditField = uieditfield(app.AllgemeinesPanel, 'numeric');
            app.BildmaeinPixelEditField.ValueChangedFcn = createCallbackFcn(app, @BildmaeinPixelEditFieldValueChanged, true);
            app.BildmaeinPixelEditField.Limits = [1 Inf];
            app.BildmaeinPixelEditField.Value = 1280;
            app.xEditFieldLabel = uilabel(app.AllgemeinesPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.AllgemeinesPanel, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Limits = [1 Inf];
            app.xEditField.Value = 720;
            app.Datenpunkttepro1000Label = uilabel(app.AllgemeinesPanel);
            app.Datenpunkttepro1000Label.Text = 'Datenpunktte pro 1000:';
            app.Datenpunkttepro1000Spinner = uispinner(app.AllgemeinesPanel);
            app.Datenpunkttepro1000Spinner.ValueChangedFcn = createCallbackFcn(app, @Datenpunkttepro1000SpinnerValueChanged, true);
            app.Datenpunkttepro1000Spinner.Limits = [1 1000];
            app.Datenpunkttepro1000Spinner.Value = 3;
            app.DatensatzwhlenButton = uibutton(app.AllgemeinesPanel, 'push');
            app.DatensatzwhlenButton.ButtonPushedFcn = createCallbackFcn(app, @DatensatzwhlenButtonPushed, true);
            app.DatensatzwhlenButton.Text = 'Datensatz wählen';
            app.KeinDatensatzgewhltLabel = uilabel(app.AllgemeinesPanel);
            app.KeinDatensatzgewhltLabel.Text = 'Kein Datensatz gewählt';
            app.AchsengrenzenPanel = uipanel(app.AllgemeinesPanel);
            app.AchsengrenzenPanel.Title = 'Achsengrenzen';
            app.AutomatischsetzenCheckBox = uicheckbox(app.AchsengrenzenPanel);
            app.AutomatischsetzenCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomatischsetzenCheckBoxValueChanged, true);
            app.AutomatischsetzenCheckBox.Text = 'Automatisch setzen';
            app.AutomatischsetzenCheckBox.Value = true;
            app.UntereGrenzeLabel = uilabel(app.AchsengrenzenPanel);
            app.UntereGrenzeLabel.Enable = 'off';
            app.UntereGrenzeLabel.Text = 'Untere Grenze';
            app.ObereGrenzeLabel = uilabel(app.AchsengrenzenPanel);
            app.ObereGrenzeLabel.Enable = 'off';
            app.ObereGrenzeLabel.Text = 'Obere Grenze';
            app.xAchseLabel = uilabel(app.AchsengrenzenPanel);
            app.xAchseLabel.Enable = 'off';
            app.xAchseLabel.Text = 'x-Achse';
            app.yAchseLabel = uilabel(app.AchsengrenzenPanel);
            app.yAchseLabel.Enable = 'off';
            app.yAchseLabel.Text = 'y-Achse';
            app.zAchseLabel = uilabel(app.AchsengrenzenPanel);
            app.zAchseLabel.Enable = 'off';
            app.zAchseLabel.Text = 'z-Achse';
            app.EditField = uieditfield(app.AchsengrenzenPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.EditField.Value = -100;
            app.EditField_2 = uieditfield(app.AchsengrenzenPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Enable = 'off';
            app.EditField_2.Value = 100;
            app.EditField_3 = uieditfield(app.AchsengrenzenPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Enable = 'off';
            app.EditField_3.Value = -100;
            app.EditField_4 = uieditfield(app.AchsengrenzenPanel, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Enable = 'off';
            app.EditField_4.Value = 100;
            app.EditField_5 = uieditfield(app.AchsengrenzenPanel, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Enable = 'off';
            app.EditField_6 = uieditfield(app.AchsengrenzenPanel, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Enable = 'off';
            app.EditField_6.Value = 200;
            app.KamerapositionPanel = uipanel(app.AllgemeinesPanel);
            app.KamerapositionPanel.Title = 'Kameraposition';
            app.AzimuthLabel = uilabel(app.KamerapositionPanel);
            app.AzimuthLabel.Text = 'Azimuth:';
            app.AzimuthEditField = uieditfield(app.KamerapositionPanel, 'numeric');
            app.AzimuthEditField.Value = 40;
            app.ElevationLabel = uilabel(app.KamerapositionPanel);
            app.ElevationLabel.Text = 'Elevation:';
            app.ElevationEditField = uieditfield(app.KamerapositionPanel, 'numeric');
            app.ElevationEditField.Value = 15;
            app.RenderButton = uibutton(app.UIFigure, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Text = 'Render';
            app.AnimationPanel = uipanel(app.UIFigure);
            app.AnimationPanel.Title = 'Animation';
            app.AnimationerstellenCheckBox = uicheckbox(app.AnimationPanel);
            app.AnimationerstellenCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnimationerstellenCheckBoxValueChanged, true);
            app.AnimationerstellenCheckBox.Text = 'Animation erstellen';
            app.EinzelneSegmenteCheckBox = uicheckbox(app.AnimationPanel);
            app.EinzelneSegmenteCheckBox.ValueChangedFcn = createCallbackFcn(app, @EinzelneSegmenteCheckBoxValueChanged, true);
            app.EinzelneSegmenteCheckBox.Enable = 'off';
            app.EinzelneSegmenteCheckBox.Text = 'Einzelne Segmente';
            app.EinzelneKurvemitBahnverlaufCheckBox = uicheckbox(app.AnimationPanel);
            app.EinzelneKurvemitBahnverlaufCheckBox.ValueChangedFcn = createCallbackFcn(app, @EinzelneKurvemitBahnverlaufCheckBoxValueChanged, true);
            app.EinzelneKurvemitBahnverlaufCheckBox.Enable = 'off';
            app.EinzelneKurvemitBahnverlaufCheckBox.Text = 'Einzelne Kurve mit Bahnverlauf';
            app.BilderproSekundeLabel = uilabel(app.AnimationPanel);
            app.BilderproSekundeLabel.Enable = 'off';
            app.BilderproSekundeLabel.Text = 'Bilder pro Sekunde:';
            app.BilderproSekundeEditField = uieditfield(app.AnimationPanel, 'numeric');
            app.BilderproSekundeEditField.ValueChangedFcn = createCallbackFcn(app, @BilderproSekundeEditFieldValueChanged, true);
            app.BilderproSekundeEditField.Limits = [1 1000];
            app.BilderproSekundeEditField.Enable = 'off';
            app.BilderproSekundeEditField.Value = 60;
            app.Zeitfaktor20Label = uilabel(app.AnimationPanel);
            app.Zeitfaktor20Label.Enable = 'off';
            app.Zeitfaktor20Label.Text = 'Zeitfaktor: 20';
            app.BedingtdurchDatenpunktepro1000undBildersLabel = uilabel(app.AnimationPanel);
            app.BedingtdurchDatenpunktepro1000undBildersLabel.Enable = 'off';
            app.BedingtdurchDatenpunktepro1000undBildersLabel.Text = '(Bedingt durch Datenpunkte pro 1000 und Bilder/s)';
            app.DateinameLabel = uilabel(app.AnimationPanel);
            app.DateinameLabel.Enable = 'off';
            app.DateinameLabel.Text = 'Dateiname:';
            app.DateinameEditField = uieditfield(app.AnimationPanel, 'text');
            app.DateinameEditField.Enable = 'off';
            app.DateinameEditField.Value = 'out';
            app.SpeicherortwhlenButton = uibutton(app.AnimationPanel, 'push');
            app.SpeicherortwhlenButton.ButtonPushedFcn = createCallbackFcn(app, @SpeicherortwhlenButtonPushed, true);
            app.SpeicherortwhlenButton.Enable = 'off';
            app.SpeicherortwhlenButton.Text = 'Speicherort wählen';
            app.TextArea_2 = uitextarea(app.AnimationPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Arbeitsverzeichnis ausgewählt'};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AktorVisualisierer2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end